classdef RoboticManipulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        LabelPhi1                     matlab.ui.control.Label
        LabelPhi2                     matlab.ui.control.Label
        LabelPhi3                     matlab.ui.control.Label
        EditFieldL1                   matlab.ui.control.NumericEditField
        EditFieldL2                   matlab.ui.control.NumericEditField
        EditFieldL3                   matlab.ui.control.NumericEditField
        maxPhi1                       matlab.ui.control.NumericEditField
        maxPhi2                       matlab.ui.control.NumericEditField
        maxPhi3                       matlab.ui.control.NumericEditField
        maxEditField_3Label           matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        SliderPhi1                    matlab.ui.control.Slider
        SliderPhi2                    matlab.ui.control.Slider
        SliderPhi3                    matlab.ui.control.Slider
        minPhi1                       matlab.ui.control.NumericEditField
        minPhi2                       matlab.ui.control.NumericEditField
        minPhi3                       matlab.ui.control.NumericEditField
        minEditField_3Label           matlab.ui.control.Label
        maxEditField_2Label           matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        minEditField_2Label           matlab.ui.control.Label
        minEditFieldLabel             matlab.ui.control.Label
        L1mmLabel                     matlab.ui.control.Label
        L2mmLabel                     matlab.ui.control.Label
        L3mmLabel                     matlab.ui.control.Label
        maxEditFieldLabel             matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        DraweverythingButton          matlab.ui.control.Button
        DrawXZworkingareaButton       matlab.ui.control.Button
        DrawXYworkingareaButton       matlab.ui.control.Button
        DrawRoboticManipulatorButton  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        l1;
        l2;
        l3;
        
        phi1_min;
        phi1_max;
        phi2_min;
        phi2_max;
        phi3_min;
        phi3_max;    
        
        phi1;
        phi2;
        phi3;

        drawRM;
        drawXY;
        drawXZ;
    end
    
    methods (Access = private)
        
        function myUpdateApp(app)
            % cs for CoordinateSystem
            cs.i(:,1) = [50; 0; 0; 1];
            cs.j(:,1) = [0; 50; 0; 1];
            cs.k(:,1) = [0; 0; 50; 1];
            bod(:,1) = [0; 0; 0; 1];
            
            cs.i(:,2) = (appRobRotate(app,'z','deg',app.phi1)*cs.i(:,1));
            cs.j(:,2) = (appRobRotate(app,'z','deg',app.phi1)*cs.j(:,1));
            cs.k(:,2) = (appRobRotate(app,'z','deg',app.phi1)*cs.k(:,1));
            
            bod(:,2) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*bod(:,1));
            cs.i(:,3) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*cs.i(:,1));
            cs.j(:,3) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*cs.j(:,1));
            cs.k(:,3) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*cs.k(:,1));
            
            cs.i(:,4) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',app.phi2)*cs.i(:,1));
            cs.j(:,4) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',app.phi2)*cs.j(:,1));
            cs.k(:,4) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',app.phi2)*cs.k(:,1));
            
            bod(:,3) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',app.phi2)*appRobTranslate(app,'z',app.l2)*bod(:,1));
            cs.i(:,5) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',app.phi2)*appRobTranslate(app,'z',app.l2)*cs.i(:,1));
            cs.j(:,5) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',app.phi2)*appRobTranslate(app,'z',app.l2)*cs.j(:,1));
            cs.k(:,5) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',app.phi2)*appRobTranslate(app,'z',app.l2)*cs.k(:,1));
            
            cs.i(:,6) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',app.phi2)*appRobTranslate(app,'z',app.l2)*appRobRotate(app,'y','deg',app.phi3)*cs.i(:,1));
            cs.j(:,6) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',app.phi2)*appRobTranslate(app,'z',app.l2)*appRobRotate(app,'y','deg',app.phi3)*cs.j(:,1));
            cs.k(:,6) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',app.phi2)*appRobTranslate(app,'z',app.l2)*appRobRotate(app,'y','deg',app.phi3)*cs.k(:,1));
            
            bod(:,4) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',app.phi2)*appRobTranslate(app,'z',app.l2)*appRobRotate(app,'y','deg',app.phi3)*appRobTranslate(app,'z',app.l3)*bod(:,1));
            cs.i(:,7) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',app.phi2)*appRobTranslate(app,'z',app.l2)*appRobRotate(app,'y','deg',app.phi3)*appRobTranslate(app,'z',app.l3)*cs.i(:,1));
            cs.j(:,7) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',app.phi2)*appRobTranslate(app,'z',app.l2)*appRobRotate(app,'y','deg',app.phi3)*appRobTranslate(app,'z',app.l3)*cs.j(:,1));
            cs.k(:,7) = (appRobRotate(app,'z','deg',app.phi1)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',app.phi2)*appRobTranslate(app,'z',app.l2)*appRobRotate(app,'y','deg',app.phi3)*appRobTranslate(app,'z',app.l3)*cs.k(:,1));
            
            xy_iter = 1;
            krok = 5;
            
            for(phi1_local = app.phi1_min:krok:app.phi1_max)
                xy(1:4,xy_iter) = 0;
                for(phi2_local = app.phi2_min:krok:0)
                    for(phi3_local = app.phi3_min:krok:app.phi3_max)
                        Ct(1:4) = (appRobRotate(app,'z','deg',phi1_local)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',phi2_local)*appRobTranslate(app,'z',app.l2)*appRobRotate(app,'y','deg',phi3_local)*appRobTranslate(app,'z',app.l3)*bod(:,1));
                        Ct(5) = sqrt(Ct(1)*Ct(1)+Ct(2)*Ct(2));
                        if(xy(4,xy_iter) < Ct(5))
                            xy(1:2,xy_iter) = Ct(1:2);
                            xy(3,xy_iter) = app.l1;
                            xy(4,xy_iter) = Ct(5);
                        end
                    end
                end
                xy_iter = xy_iter + 1;
            end
            
            for(phi1_local = app.phi1_min:krok:app.phi1_max)
                xy(1:4,xy_iter) = 0;
                for(phi2_local = 0:krok:app.phi2_max)
                    for(phi3_local = app.phi3_min:krok:app.phi3_max)
                        Ct(1:4) = (appRobRotate(app,'z','deg',phi1_local)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',phi2_local)*appRobTranslate(app,'z',app.l2)*appRobRotate(app,'y','deg',phi3_local)*appRobTranslate(app,'z',app.l3)*bod(:,1));
                        Ct(5) = sqrt(Ct(1)*Ct(1)+Ct(2)*Ct(2));
                        if(xy(4,xy_iter) < Ct(5))
                            xy(1:2,xy_iter) = Ct(1:2);
                            xy(3,xy_iter) = app.l1;
                            xy(4,xy_iter) = Ct(5);
                        end
                    end
                end
                xy_iter = xy_iter + 1;
            end
            xy(1:4,xy_iter)=xy(1:4,1);
            
            xz_iter = 1;
            krok = 5;
            
            phi1_local = 0;
            phi2_local = app.phi2_min;
            
            for(phi3_local = app.phi3_min:krok:app.phi3_max)
                Ct(1:4) = (appRobRotate(app,'z','deg',phi1_local)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',phi2_local)*appRobTranslate(app,'z',app.l2)*appRobRotate(app,'y','deg',phi3_local)*appRobTranslate(app,'z',app.l3)*bod(:,1));
                Ct(5) = sqrt(Ct(1)*Ct(1)+Ct(2)*Ct(2));
            
                xz(1:3,xz_iter) = Ct(1:3);
                xz(4,xz_iter) = Ct(5);
            
                xz_iter = xz_iter + 1;
            end
            
            phi3_local = app.phi3_max;
            
            for(phi2_local = app.phi2_min:krok:app.phi2_max)
                Ct(1:4) = (appRobRotate(app,'z','deg',phi1_local)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',phi2_local)*appRobTranslate(app,'z',app.l2)*appRobRotate(app,'y','deg',phi3_local)*appRobTranslate(app,'z',app.l3)*bod(:,1));
                Ct(5) = sqrt(Ct(1)*Ct(1)+Ct(2)*Ct(2));
            
                xz(1:3,xz_iter) = Ct(1:3);
                xz(4,xz_iter) = Ct(5);
            
                xz_iter = xz_iter + 1;
            end
            
            phi2_local = app.phi2_max;
            
            for(phi3_local = app.phi3_max:-krok:app.phi3_min)
                Ct(1:4) = (appRobRotate(app,'z','deg',phi1_local)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',phi2_local)*appRobTranslate(app,'z',app.l2)*appRobRotate(app,'y','deg',phi3_local)*appRobTranslate(app,'z',app.l3)*bod(:,1));
                Ct(5) = sqrt(Ct(1)*Ct(1)+Ct(2)*Ct(2));
            
                xz(1:3,xz_iter) = Ct(1:3);
                xz(4,xz_iter) = Ct(5);
            
                xz_iter = xz_iter + 1;
            end
            
            phi3_local = app.phi3_min;
            
            for(phi2_local = app.phi2_max:-krok:app.phi2_min)
                Ct(1:4) = (appRobRotate(app,'z','deg',phi1_local)*appRobTranslate(app,'z',app.l1)*appRobRotate(app,'y','deg',phi2_local)*appRobTranslate(app,'z',app.l2)*appRobRotate(app,'y','deg',phi3_local)*appRobTranslate(app,'z',app.l3)*bod(:,1));
                Ct(5) = sqrt(Ct(1)*Ct(1)+Ct(2)*Ct(2));
            
                xz(1:3,xz_iter) = Ct(1:3);
                xz(4,xz_iter) = Ct(5);
            
                xz_iter = xz_iter + 1;
            end
            
            
            cla(app.UIAxes);
%             grid(app.UIAxes,'on');
%             axis(app.UIAxes,'equal');
%             hold(app.UIAxes,'on');
%             view(app.UIAxes,[40 30])
%             xlabel(app.UIAxes,'x');
%             ylabel(app.UIAxes,'y');
%             zlabel(app.UIAxes,'z');
            if((app.drawRM == true)||(app.drawRM == true && app.drawXY == true && app.drawXZ == true))
                view(app.UIAxes,[40 30])
            elseif (app.drawXY == true)
                view(app.UIAxes,[0 90])
            elseif (app.drawXZ == true)
                view(app.UIAxes,[0 0])
            end
            
            if(app.drawRM == true)
                for (iterator = 2:1:length(bod))
                    appRobPlot3(app,app.UIAxes,bod(:,iterator-1),bod(:,iterator))
                    appRobSphere(app,app.UIAxes,7,bod(:,iterator))
                end
                
                plot3(app.UIAxes,[bod(1,1),cs.i(1,2)],[bod(2,1),cs.i(2,2)],[bod(3,1),cs.i(3,2)],'Color','red','LineWidth',2)
                plot3(app.UIAxes,[bod(1,1),cs.j(1,2)],[bod(2,1),cs.j(2,2)],[bod(3,1),cs.j(3,2)],'Color','green','LineWidth',2)
                plot3(app.UIAxes,[bod(1,1),cs.k(1,2)],[bod(2,1),cs.k(2,2)],[bod(3,1),cs.k(3,2)],'Color','blue','LineWidth',2)
                
                plot3(app.UIAxes,[bod(1,2),cs.i(1,3)],[bod(2,2),cs.i(2,3)],[bod(3,2),cs.i(3,3)],'Color','red','LineWidth',2)
                plot3(app.UIAxes,[bod(1,2),cs.j(1,3)],[bod(2,2),cs.j(2,3)],[bod(3,2),cs.j(3,3)],'Color','green','LineWidth',2)
                plot3(app.UIAxes,[bod(1,2),cs.k(1,3)],[bod(2,2),cs.k(2,3)],[bod(3,2),cs.k(3,3)],'Color','blue','LineWidth',2)
                
                plot3(app.UIAxes,[bod(1,3),cs.i(1,5)],[bod(2,3),cs.i(2,5)],[bod(3,3),cs.i(3,5)],'Color','red','LineWidth',2)
                plot3(app.UIAxes,[bod(1,3),cs.j(1,5)],[bod(2,3),cs.j(2,5)],[bod(3,3),cs.j(3,5)],'Color','green','LineWidth',2)
                plot3(app.UIAxes,[bod(1,3),cs.k(1,5)],[bod(2,3),cs.k(2,5)],[bod(3,3),cs.k(3,5)],'Color','blue','LineWidth',2)
                
                plot3(app.UIAxes,[bod(1,4),cs.i(1,7)],[bod(2,4),cs.i(2,7)],[bod(3,4),cs.i(3,7)],'Color','red','LineWidth',2)
                plot3(app.UIAxes,[bod(1,4),cs.j(1,7)],[bod(2,4),cs.j(2,7)],[bod(3,4),cs.j(3,7)],'Color','green','LineWidth',2)
                plot3(app.UIAxes,[bod(1,4),cs.k(1,7)],[bod(2,4),cs.k(2,7)],[bod(3,4),cs.k(3,7)],'Color','blue','LineWidth',2)
            end

            if(app.drawXY == true)
                fill3(app.UIAxes,xy(1,:),xy(2,:),xy(3,:),'r','EdgeColor','none','FaceColor','red','FaceAlpha','0.2');
                plot3(app.UIAxes,xy(1,:),xy(2,:),xy(3,:),'Color','red','LineWidth',2);
            end

            if(app.drawXZ == true)
                fill3(app.UIAxes,xz(1,:),xz(2,:),xz(3,:),'b','EdgeColor','none','FaceColor','blue','FaceAlpha','0.2');
                plot3(app.UIAxes,xz(1,:),xz(2,:),xz(3,:),'Color','blue','LineWidth',2);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.l1 = 203;
            app.l2 = 178;
            app.l3 = 178;
            
            app.phi1_min = -90;
            app.phi1_max = 90;
            app.phi2_min = -55;
            app.phi2_max = 125;
            app.phi3_min = 0;
            app.phi3_max = 150;    
            
            app.phi1 = 0;
            app.phi2 = 45;
            app.phi3 = 90;

            app.drawRM = true;
            app.drawXY = true;
            app.drawXZ = true;

            app.EditFieldL1.Value = app.l1;
            app.EditFieldL2.Value = app.l2;
            app.EditFieldL3.Value = app.l3;

            app.maxPhi1.Value = app.phi1_max;
            app.maxPhi2.Value = app.phi2_max;
            app.maxPhi3.Value = app.phi3_max;

            app.SliderPhi1.Value = app.phi1;
            app.LabelPhi1.Text = int2str(app.phi1);
            app.SliderPhi1.Limits = [app.phi1_min, app.phi1_max];
            app.SliderPhi1.MajorTicks = (app.phi1_min:10:app.phi1_max);

            app.SliderPhi2.Value = app.phi2;
            app.LabelPhi2.Text = int2str(app.phi2);
            app.SliderPhi2.Limits = [app.phi2_min, app.phi2_max];
            app.SliderPhi2.MajorTicks = (app.phi2_min:10:app.phi2_max);

            app.SliderPhi3.Value = app.phi3;
            app.LabelPhi3.Text = int2str(app.phi3);
            app.SliderPhi3.Limits = [app.phi3_min, app.phi3_max];
            app.SliderPhi3.MajorTicks = (app.phi3_min:10:app.phi3_max);

            app.minPhi1.Value = app.phi1_min;
            app.minPhi2.Value = app.phi2_min;
            app.minPhi3.Value = app.phi3_min;

            cla(app.UIAxes);
            grid(app.UIAxes,'on');
            axis(app.UIAxes,'equal');
            hold(app.UIAxes,'on');
            view(app.UIAxes,[40 30])
            xlabel(app.UIAxes,'x');
            ylabel(app.UIAxes,'y');
            zlabel(app.UIAxes,'z');

            app.myUpdateApp();
        end

        % Value changed function: EditFieldL1
        function EditFieldL1ValueChanged(app, event)
            app.l1 = app.EditFieldL1.Value;
            app.myUpdateApp();
        end

        % Value changed function: EditFieldL2
        function EditFieldL2ValueChanged(app, event)
            app.l2 = app.EditFieldL2.Value;
            app.myUpdateApp();
        end

        % Value changed function: EditFieldL3
        function EditFieldL3ValueChanged(app, event)
            app.l3 = app.EditFieldL3.Value;
            app.myUpdateApp();
        end

        % Value changed function: SliderPhi1
        function SliderPhi1ValueChanged(app, event)
            app.phi1 = app.SliderPhi1.Value;
            app.LabelPhi1.Text = int2str(app.phi1);
            app.myUpdateApp();
        end

        % Value changed function: SliderPhi2
        function SliderPhi2ValueChanged(app, event)
            app.phi2 = app.SliderPhi2.Value;
            app.LabelPhi2.Text = int2str(app.phi2);
            app.myUpdateApp();
        end

        % Value changed function: SliderPhi3
        function SliderPhi3ValueChanged(app, event)
            app.phi3 = app.SliderPhi3.Value;
            app.LabelPhi3.Text = int2str(app.phi3);
            app.myUpdateApp();
        end

        % Value changed function: maxPhi1
        function maxPhi1ValueChanged(app, event)
            app.phi1_max = app.maxPhi1.Value;
            app.SliderPhi1.Limits = [app.phi1_min, app.phi1_max];
            app.SliderPhi1.MajorTicks = (app.phi1_min:10:app.phi1_max);

            if(app.SliderPhi1.Value > app.phi1_max)
                app.phi1 = app.phi1_max;
                app.LabelPhi1.Text = int2str(app.phi1);
                app.myUpdateApp();
            end
        end

        % Value changed function: maxPhi2
        function maxPhi2ValueChanged(app, event)
            app.phi1_max = app.maxPhi2.Value;
            app.SliderPhi2.Limits = [app.phi2_min, app.phi2_max];
            app.SliderPhi2.MajorTicks = (app.phi2_min:10:app.phi2_max);

            if(app.SliderPhi2.Value > app.phi2_max)
                app.phi1 = app.phi1_max;
                app.LabelPhi2.Text = int2str(app.phi2);
                app.myUpdateApp();
            end
        end

        % Value changed function: maxPhi3
        function maxPhi3ValueChanged(app, event)
            app.phi3_max = app.maxPhi3.Value;
            app.SliderPhi3.Limits = [app.phi3_min, app.phi3_max];
            app.SliderPhi3.MajorTicks = (app.phi3_min:10:app.phi3_max);

            if(app.SliderPhi3.Value > app.phi3_max)
                app.phi3 = app.phi3_max;
                app.LabelPhi3.Text = int2str(app.phi3);
                app.myUpdateApp();
            end
        end

        % Value changed function: minPhi1
        function minPhi1ValueChanged(app, event)
            app.phi1_min = app.minPhi1.Value;
            app.SliderPhi1.Limits = [app.phi1_min, app.phi1_max];
            app.SliderPhi1.MajorTicks = (app.phi1_min:10:app.phi1_max);

            if(app.SliderPhi1.Value < app.phi1_min)
                app.phi1 = app.phi1_min;
                app.LabelPhi1.Text = int2str(app.phi1);
                app.myUpdateApp();
            end
        end

        % Value changed function: minPhi2
        function minPhi2ValueChanged(app, event)
            app.phi2_min = app.minPhi2.Value;
            app.SliderPhi2.Limits = [app.phi2_min, app.phi2_max];
            app.SliderPhi2.MajorTicks = (app.phi2_min:10:app.phi2_max);

            if(app.SliderPhi2.Value < app.phi2_min)
                app.phi2 = app.phi2_min;
                app.LabelPhi2.Text = int2str(app.phi2);
                app.myUpdateApp();
            end
        end

        % Value changed function: minPhi3
        function minPhi3ValueChanged(app, event)
            app.phi3_min = app.minPhi3.Value;
            app.SliderPhi3.Limits = [app.phi3_min, app.phi3_max];
            app.SliderPhi3.MajorTicks = (app.phi3_min:10:app.phi3_max);

            if(app.SliderPhi3.Value < app.phi3_min)
                app.phi3 = app.phi3_min;
                app.LabelPhi3.Text = int2str(app.phi3);
                app.myUpdateApp();
            end
        end

        % Button pushed function: DraweverythingButton
        function DraweverythingButtonPushed(app, event)
            app.drawRM = true;
            app.drawXY = true;
            app.drawXZ = true;
            app.myUpdateApp();
        end

        % Button pushed function: DrawXZworkingareaButton
        function DrawXZworkingareaButtonPushed(app, event)
            app.drawRM = false;
            app.drawXY = false;
            app.drawXZ = true;
            app.myUpdateApp();
        end

        % Button pushed function: DrawXYworkingareaButton
        function DrawXYworkingareaButtonPushed(app, event)
            app.drawRM = false;
            app.drawXY = true;
            app.drawXZ = false;
            app.myUpdateApp();
        end

        % Button pushed function: DrawRoboticManipulatorButton
        function DrawRoboticManipulatorButtonPushed(app, event)
            app.drawRM = true;
            app.drawXY = false;
            app.drawXZ = false;
            app.myUpdateApp();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {475, 475};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {217, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'φ1 [deg]';
            app.maxEditFieldLabel = uilabel(app.LeftPanel);
            app.maxEditFieldLabel.Text = 'φ1 max';
            app.L3mmLabel = uilabel(app.LeftPanel);
            app.L3mmLabel.Text = 'L3 [mm]';
            app.L2mmLabel = uilabel(app.LeftPanel);
            app.L2mmLabel.Text = 'L2 [mm]';
            app.L1mmLabel = uilabel(app.LeftPanel);
            app.L1mmLabel.Text = 'L1 [mm]';
            app.minEditFieldLabel = uilabel(app.LeftPanel);
            app.minEditFieldLabel.Text = 'φ1 min';
            app.minEditField_2Label = uilabel(app.LeftPanel);
            app.minEditField_2Label.Text = 'φ2 min';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'φ2 [deg]';
            app.maxEditField_2Label = uilabel(app.LeftPanel);
            app.maxEditField_2Label.Text = 'φ2 max';
            app.minEditField_3Label = uilabel(app.LeftPanel);
            app.minEditField_3Label.Text = 'φ3 min';
            app.minPhi3 = uieditfield(app.LeftPanel, 'numeric');
            app.minPhi3.ValueChangedFcn = createCallbackFcn(app, @minPhi3ValueChanged, true);
            app.minPhi2 = uieditfield(app.LeftPanel, 'numeric');
            app.minPhi2.ValueChangedFcn = createCallbackFcn(app, @minPhi2ValueChanged, true);
            app.minPhi1 = uieditfield(app.LeftPanel, 'numeric');
            app.minPhi1.ValueChangedFcn = createCallbackFcn(app, @minPhi1ValueChanged, true);
            app.SliderPhi3 = uislider(app.LeftPanel);
            app.SliderPhi3.MajorTicks = [];
            app.SliderPhi3.ValueChangedFcn = createCallbackFcn(app, @SliderPhi3ValueChanged, true);
            app.SliderPhi2 = uislider(app.LeftPanel);
            app.SliderPhi2.MajorTicks = [];
            app.SliderPhi2.ValueChangedFcn = createCallbackFcn(app, @SliderPhi2ValueChanged, true);
            app.SliderPhi1 = uislider(app.LeftPanel);
            app.SliderPhi1.MajorTicks = [];
            app.SliderPhi1.ValueChangedFcn = createCallbackFcn(app, @SliderPhi1ValueChanged, true);
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = 'φ3 [deg]';
            app.maxEditField_3Label = uilabel(app.LeftPanel);
            app.maxEditField_3Label.Text = 'φ3 max';
            app.maxPhi3 = uieditfield(app.LeftPanel, 'numeric');
            app.maxPhi3.ValueChangedFcn = createCallbackFcn(app, @maxPhi3ValueChanged, true);
            app.maxPhi2 = uieditfield(app.LeftPanel, 'numeric');
            app.maxPhi2.ValueChangedFcn = createCallbackFcn(app, @maxPhi2ValueChanged, true);
            app.maxPhi1 = uieditfield(app.LeftPanel, 'numeric');
            app.maxPhi1.ValueChangedFcn = createCallbackFcn(app, @maxPhi1ValueChanged, true);
            app.EditFieldL3 = uieditfield(app.LeftPanel, 'numeric');
            app.EditFieldL3.Limits = [0 Inf];
            app.EditFieldL3.ValueChangedFcn = createCallbackFcn(app, @EditFieldL3ValueChanged, true);
            app.EditFieldL2 = uieditfield(app.LeftPanel, 'numeric');
            app.EditFieldL2.Limits = [0 Inf];
            app.EditFieldL2.ValueChangedFcn = createCallbackFcn(app, @EditFieldL2ValueChanged, true);
            app.EditFieldL1 = uieditfield(app.LeftPanel, 'numeric');
            app.EditFieldL1.Limits = [0 Inf];
            app.EditFieldL1.ValueChangedFcn = createCallbackFcn(app, @EditFieldL1ValueChanged, true);
            app.LabelPhi3 = uilabel(app.LeftPanel);
            app.LabelPhi2 = uilabel(app.LeftPanel);
            app.LabelPhi1 = uilabel(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Robotic Manipulator')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DrawRoboticManipulatorButton = uibutton(app.RightPanel, 'push');
            app.DrawRoboticManipulatorButton.ButtonPushedFcn = createCallbackFcn(app, @DrawRoboticManipulatorButtonPushed, true);
            app.DrawRoboticManipulatorButton.Text = 'Draw Robotic Manipulator';
            app.DrawXYworkingareaButton = uibutton(app.RightPanel, 'push');
            app.DrawXYworkingareaButton.ButtonPushedFcn = createCallbackFcn(app, @DrawXYworkingareaButtonPushed, true);
            app.DrawXYworkingareaButton.Text = 'Draw XY working area';
            app.DrawXZworkingareaButton = uibutton(app.RightPanel, 'push');
            app.DrawXZworkingareaButton.ButtonPushedFcn = createCallbackFcn(app, @DrawXZworkingareaButtonPushed, true);
            app.DrawXZworkingareaButton.Text = 'Draw XZ working area';
            app.DraweverythingButton = uibutton(app.RightPanel, 'push');
            app.DraweverythingButton.ButtonPushedFcn = createCallbackFcn(app, @DraweverythingButtonPushed, true);
            app.DraweverythingButton.Text = 'Draw everything';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoboticManipulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end